classdef MapHandler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure              % UI Figure
        Panel              matlab.ui.container.Panel     % Console
        Button_loadMain    matlab.ui.control.Button      % load main
        Button_showMain    matlab.ui.control.Button      % show
        Button_selectMain  matlab.ui.control.Button      % select
        Button_loadExtra   matlab.ui.control.Button      % load extra
        Button_showExtra   matlab.ui.control.Button      % show
        Button_selectExtra matlab.ui.control.Button      % select
        StateButton_cover  matlab.ui.control.StateButton % cover
        LabelSlider        matlab.ui.control.Label       % alpha
        Slider_alpha       matlab.ui.control.Slider      % [0 1]
        Button_moveLeft    matlab.ui.control.Button      % <
        Button_moveRight   matlab.ui.control.Button      % >
        Button_moveUp      matlab.ui.control.Button      % ^
        Button_moveDown    matlab.ui.control.Button      % v
        LabelDropDown      matlab.ui.control.Label       % step
        DropDown_step      matlab.ui.control.DropDown    % 1, 2, 5, 10, 20,...
        Button_write       matlab.ui.control.Button      % write
        Button_split       matlab.ui.control.Button      % split
        LabelEditField2    matlab.ui.control.Label       % pattern
        EditField_pattern  matlab.ui.control.EditField  
        LabelSpinner       matlab.ui.control.Label       % max-zoom
        Spinner_maxZoom    matlab.ui.control.Spinner     % [1 8]
        Button_extraCut    matlab.ui.control.Button      % cut
        LabelListBox       matlab.ui.control.Label       % tip
        ListBox_tip        matlab.ui.control.ListBox     % , , 
    end


    properties (Access = public)
        main_size         int32
        main_pic          uint8                      
        main_alpha        uint8
        main_select       int32
        main_fig          matlab.ui.Figure
        extra_size        int32
        extra_pic         uint8
        extra_alpha       uint8
        extra_select      int32
        extra_fig         matlab.ui.Figure
        offset            int32
        range             int32
        cover             struct
        step              int32
        value             single
        tmpfig            matlab.ui.Figure
    end

    methods (Access = public)
        
        function results = messageHandler(app, varargin)
            results = strjoin(varargin);
            item = app.ListBox_tip.Items;
            n = min(10, length(item));
            app.ListBox_tip.Items = {results, item{1:n}};
            %%app.EditField_tip.Value = results;
        end

        function pic = override(app, part)
            r = app.range;
            npic = single(app.main_pic(r(1):r(2), r(3):r(4), :));
            na = single(app.main_alpha(r(1):r(2), r(3):r(4))) / 255;
            s = [1,1;app.extra_size(1), app.extra_size(2)];
            ppic = single(app.extra_pic(s(1):s(2), s(3):s(4), :));
            pa = single(app.extra_alpha(s(1):s(2), s(3):s(4))) / 255;
            ppa = repmat(pa, [1, 1, 3]);
            npic = npic .* (1 - ppa) + ppic .* ppa;
            na = max(na, pa);
            if part
                pic = app.main_pic;
                pic(r(1):r(2), r(3):r(4), :) = uint8(npic);
            else
                app.main_pic(r(1):r(2), r(3):r(4), :) = uint8(npic);
                app.main_alpha(r(1):r(2), r(3):r(4)) = uint8(na * 255);
                pic = [];
            end
        end

        function [indexs, fmt] = getIndex(~, pattern)
            indexs = zeros(1, 3);
            [m, u] = regexp(pattern,'\{[xyz]\}','match','split');
            for n = 1 : 3
               switch m{n}
                   case '{x}'
                       indexs(n) = 1;
                       continue;
                   case '{y}'
                       indexs(n) = 2;
                       continue;
                   case '{z}'
                       indexs(n) = 3;
                       continue;
               end
            end
            fmt = strjoin(u, '%d');
        end

        function updates = splitMap2(app, pic, alpha, maxZoom, path, fmt, index)
            c = 0;
            updates = {};
            [h, w, ~] = size(pic);
            t = pow2(maxZoom);
            dh = ceil(h / t);
            dw = ceil(w / t);
            if dh * t > h || dw * t > w
               pic(dh * t, dw * t, 3) = 0; 
            end
            rh0 = int32(1 : dh);
            rw0 = int32(1 : dw);
            for z = maxZoom : -1 : 0
                for x = 0 : t - 1
                    for y = 0 : t - 1
                        rh = rh0 + y * dh;
                        rw = rw0 + x * dw;
                        tmp = [x, y, z];
                        fname = [path, '\', sprintf(fmt, tmp(index(1)), tmp(index(2)), tmp(index(3)))];
                        picpart = pic(rh, rw, :);
                        alphapart = alpha(rh, rw);
                        tmp = true;
                        try
                            [epic, ~, ealpha] = imread(fname);
                            if all(epic == picpart)
                                tmp = false;
                            end
                        catch e
                            
                        end
                        if tmp && any(alphapart(:) > 16)
                            c = c + 1;
                            imwrite(picpart, fname, 'Alpha', alphapart);
                            updates{c} = fname;
                            messageHandler(app, 'saved: ', fname);
                        end
                    end
                end
                pic = imresize(pic, 0.5);
                alpha = imresize(alpha, 0.5);
                t = t / 2;
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button_loadMain button pushed function
        function load_main(app)
            [f, p] = uigetfile('*.png');
            file = [p, f];
            try
                messageHandler(app, 'loading...');
                [pic, ~, alpha] = imread(file);
                [h, w, ~] = size(pic);
                app.main_size = [h, w];
                app.main_pic = pic;
                app.main_alpha = alpha;
                app.main_select = [1, 1; h, w];
                messageHandler(app, 'success: ', file);
            catch e
                messageHandler(app, 'exception: ', e.message);
            end 
        end

        % Button_showMain button pushed function
        function show_main(app)
            if ~isempty(app.main_size)
                if ~isempty(app.main_fig)
                   delete(app.main_fig); 
                end
                app.main_fig = figure();
                imshow(app.main_pic);
                title('main');
                figure(app.UIFigure)
            end
        end

        % Button_selectMain button pushed function
        function select_main(app)
            if ~isempty(app.main_fig)
                figure(app.main_fig);
                [x, y] = ginput(2);
                sel = int32([min(y), min(x); max(y) max(x)]);
                imshow(app.main_pic(sel(1):sel(2), sel(3):sel(4), :));
                app.main_select = sel;
                figure(app.UIFigure);
            end
        end

        % Button_loadExtra button pushed function
        function load_extra(app)
            [f, p] = uigetfile('*.png');
            file = [p, f];
            try
                messageHandler(app, 'loading...');
                [pic, ~, alpha] = imread(file);
                [h, w, ~] = size(pic);
                app.extra_size = [h, w];
                app.extra_pic = pic;
                app.extra_alpha = alpha;
                app.extra_select = [1, 1; h, w];
                messageHandler(app, 'success: ', file);
            catch e
                messageHandler(app, 'exception: ', e.message);
            end
        end

        % Button_showExtra button pushed function
        function show_extra(app)
            if ~isempty(app.extra_size)
                if ~isempty(app.extra_fig)
                   delete(app.extra_fig); 
                end
                app.extra_fig = figure();
                imshow(app.extra_pic);
                title('extra');
                figure(app.UIFigure)
            end
        end

        % Button_selectExtra button pushed function
        function select_extra(app)
            if ~isempty(app.extra_fig)
                figure(app.extra_fig);
                [x, y] = ginput(2);
                sel = int32([min(y), min(x); max(y) max(x)]);
                imshow(app.extra_pic(sel(1):sel(2), sel(3):sel(4), :));
                app.extra_select = sel;
                figure(app.UIFigure);
            end
        end

        % StateButton_cover value changed function
        function cover_change(app)
            value = app.StateButton_cover.Value;
            if value
                if isempty(app.step)
                   app.step = 1; 
                end
                if isempty(app.value)
                    app.value = 0.5;
                end
                ms = app.main_select;
                mh = ms(2) - ms(1) + 1;
                mw = ms(4) - ms(3) + 1;
                es = app.extra_select;
                eh = es(2) - es(1) + 1;
                ew = es(4) - es(3) + 1;
                if mh < eh || mw < ew
                    messageHandler(app, 'fail: size');
                    app.StateButton_cover.Value = false;
                else
                    mp = single(app.main_pic(ms(1):ms(2), ms(3):ms(4), :));
                    ep = single(app.extra_pic(es(1):es(2), es(3):es(4), :));
                    ea = single(app.extra_alpha(es(1):es(2), es(3):es(4))) / 255;
                    fig = figure();
                    offx = int32(1);
                    offy = int32(1);
                    app.cover = struct('mp', mp, 'ep', ep, 'ea', ea, 'fig', fig, 'offx', offx, 'offy', offy, 'eh', eh, 'ew', ew);
                    
                    alpha = repmat(ea, [1,1,3]) * app.value;
                    np = mp;
                    np(offy : offy + eh - 1, offx : offx + ew - 1, :) = mp(offy : offy + eh - 1, offx : offx + ew - 1, :) .* (1 - alpha) + ep .* alpha;                   
                    imshow(uint8(np));
                    figure(app.UIFigure);
                end
            else
                app.offset = [app.cover.offy, app.cover.offx];
                r = app.main_select(1,:) + app.offset - app.extra_select(1,:);
                app.range = [r; r + app.extra_size() - 1];
                messageHandler(app, mat2str(app.range));
                imshow(override(app, true));
                if ~isempty(app.tmpfig)
                   delete(app.tmpfig); 
                end
                app.tmpfig = app.cover.fig;
                app.cover = [];
                delete(app.main_fig);
                delete(app.extra_fig);
            end
        end

        % Slider_alpha value changed function
        function alpha_change(app)
            app.value = single(app.Slider_alpha.Value);
            if ~isempty(app.cover)
                figure(app.cover.fig);
                alpha = repmat(app.cover.ea, [1,1,3]) * app.value;
                np = app.cover.mp;
                np(app.cover.offy : app.cover.offy + app.cover.eh - 1, app.cover.offx : app.cover.offx + app.cover.ew - 1, :) = app.cover.mp(app.cover.offy : app.cover.offy + app.cover.eh - 1, app.cover.offx : app.cover.offx + app.cover.ew - 1, :) .* (1 - alpha) + app.cover.ep .* alpha;
                imshow(uint8(np));
                figure(app.UIFigure);
            end
        end

        % DropDown_step value changed function
        function step_change(app, event)
            value = app.DropDown_step.Value;
            app.step = int32(str2double(value));
        end

        % Button_moveLeft button pushed function
        function moveLeft(app)
            if ~isempty(app.cover)
                m = app.cover.offx - 1;
                if m > 0
                    m = min(m, app.step);
                    app.cover.offx = app.cover.offx - m;
                    figure(app.cover.fig);
                    alpha = repmat(app.cover.ea, [1,1,3]) * app.value;
                    np = app.cover.mp;
                    np(app.cover.offy : app.cover.offy + app.cover.eh - 1, app.cover.offx : app.cover.offx + app.cover.ew - 1, :) = app.cover.mp(app.cover.offy : app.cover.offy + app.cover.eh - 1, app.cover.offx : app.cover.offx + app.cover.ew - 1, :) .* (1 - alpha) + app.cover.ep .* alpha;
                    imshow(uint8(np));
                    figure(app.UIFigure);
                end
            end
        end

        % Button_moveRight button pushed function
        function moveRight(app)
            if ~isempty(app.cover)
                m = size(app.cover.mp, 2) - app.cover.ew - app.cover.offx;
                if m > 0
                    m = min(m, app.step);
                    app.cover.offx = app.cover.offx + m;
                    figure(app.cover.fig);
                    alpha = repmat(app.cover.ea, [1,1,3]) * app.value;
                    np = app.cover.mp;
                    np(app.cover.offy : app.cover.offy + app.cover.eh - 1, app.cover.offx : app.cover.offx + app.cover.ew - 1, :) = app.cover.mp(app.cover.offy : app.cover.offy + app.cover.eh - 1, app.cover.offx : app.cover.offx + app.cover.ew - 1, :) .* (1 - alpha) + app.cover.ep .* alpha;
                    imshow(uint8(np));
                    figure(app.UIFigure);
                end
            end
        end

        % Button_moveUp button pushed function
        function moveUp(app)
            if ~isempty(app.cover)
                m = app.cover.offy - 1;
                if m > 0
                    m = min(m, app.step);
                    app.cover.offy = app.cover.offy - m;
                    figure(app.cover.fig);
                    alpha = repmat(app.cover.ea, [1,1,3]) * app.value;
                    np = app.cover.mp;
                    np(app.cover.offy : app.cover.offy + app.cover.eh - 1, app.cover.offx : app.cover.offx + app.cover.ew - 1, :) = app.cover.mp(app.cover.offy : app.cover.offy + app.cover.eh - 1, app.cover.offx : app.cover.offx + app.cover.ew - 1, :) .* (1 - alpha) + app.cover.ep .* alpha;
                    imshow(uint8(np));
                    figure(app.UIFigure);
                end
            end
        end

        % Button_moveDown button pushed function
        function moveDown(app)
            if ~isempty(app.cover)
                m = size(app.cover.mp, 1) - app.cover.eh - app.cover.offy;
                if m > 0
                    m = min(m, app.step);
                    app.cover.offy = app.cover.offy + m;
                    figure(app.cover.fig);
                    alpha = repmat(app.cover.ea, [1,1,3]) * app.value;
                    np = app.cover.mp;
                    np(app.cover.offy : app.cover.offy + app.cover.eh - 1, app.cover.offx : app.cover.offx + app.cover.ew - 1, :) = app.cover.mp(app.cover.offy : app.cover.offy + app.cover.eh - 1, app.cover.offx : app.cover.offx + app.cover.ew - 1, :) .* (1 - alpha) + app.cover.ep .* alpha;
                    imshow(uint8(np));
                    figure(app.UIFigure);
                end
            end
        end

        % UIFigure close request function
        function close(app)
            delete(app.main_fig);
            app.main_pic = [];
            app.main_alpha = [];
            delete(app.extra_fig);
            app.extra_pic = [];
            app.extra_alpha = [];
            messageHandler(app, '#end');
            delete(app.tmpfig);
            delete(app);
        end

        % Button_write button pushed function
        function write(app)
            if ~isempty(app.main_size)
                [f, p] = uiputfile('.png', 'save map as:');
                file = [p, f];
                override(app, false);
                imwrite(app.main_pic, file, 'Alpha', app.main_alpha);
                messageHandler(app, 'saved: ', file);
                figure(app.UIFigure);
            end
        end

        % Button_split button pushed function
        function split(app)
            p = uigetdir();
            level = app.Spinner_maxZoom.Value;
            pattern = app.EditField_pattern.Value;
            [ind, fmt] = getIndex(app, pattern);
            updates = splitMap2(app, app.main_pic, app.main_alpha, level, p, fmt, ind);
            fid = fopen([p, '\', 'updates.csv'], 'a');
            fprintf(fid, '#,%s,%d\n', datestr(now, 31), length(updates));
            fprintf(fid, '%s\n', updates{:});
            fprintf(fid, '\n');
            fclose(fid);
            messageHandler(app, 'finished +', num2str(length(updates)));
        end

        % Button_extraCut button pushed function
        function cut_extra(app)
            if ~isempty(app.extra_fig)
                figure(app.extra_fig);
                [x, y] = ginput(2);
                if length(x) == 2
                    sel = int32([min(y), min(x); max(y) max(x)]);
                    app.extra_pic = app.extra_pic(sel(1):sel(2), sel(3):sel(4), :);
                    app.extra_alpha = app.extra_alpha(sel(1):sel(2), sel(3):sel(4), :);
                    imshow(app.extra_pic);
                    app.extra_select = [1, 1; sel(2) - sel(1) + 1, sel(4) - sel(3) + 1];
                    app.extra_size = [sel(2) - sel(1) + 1, sel(4) - sel(3) + 1];
                    figure(app.UIFigure);
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @close);
            setAutoResize(app, app.UIFigure, true)
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Console';
            app.Panel.Units = 'pixels';
            app.Button_loadMain = uibutton(app.Panel, 'push');
            app.Button_loadMain.ButtonPushedFcn = createCallbackFcn(app, @load_main);
            app.Button_loadMain.Text = 'load main';
            app.Button_showMain = uibutton(app.Panel, 'push');
            app.Button_showMain.ButtonPushedFcn = createCallbackFcn(app, @show_main);
            app.Button_showMain.Text = 'show';
            app.Button_selectMain = uibutton(app.Panel, 'push');
            app.Button_selectMain.ButtonPushedFcn = createCallbackFcn(app, @select_main);
            app.Button_selectMain.Text = 'select';
            app.Button_loadExtra = uibutton(app.Panel, 'push');
            app.Button_loadExtra.ButtonPushedFcn = createCallbackFcn(app, @load_extra);
            app.Button_loadExtra.Text = 'load extra';
            app.Button_showExtra = uibutton(app.Panel, 'push');
            app.Button_showExtra.ButtonPushedFcn = createCallbackFcn(app, @show_extra);
            app.Button_showExtra.Text = 'show';
            app.Button_selectExtra = uibutton(app.Panel, 'push');
            app.Button_selectExtra.ButtonPushedFcn = createCallbackFcn(app, @select_extra);
            app.Button_selectExtra.Text = 'select';
            app.StateButton_cover = uibutton(app.Panel, 'state');
            app.StateButton_cover.ValueChangedFcn = createCallbackFcn(app, @cover_change);
            app.StateButton_cover.Text = 'cover';
            app.LabelSlider = uilabel(app.Panel);
            app.LabelSlider.Text = 'alpha';
            app.Slider_alpha = uislider(app.Panel);
            app.Slider_alpha.Limits = [0 1];
            app.Slider_alpha.ValueChangedFcn = createCallbackFcn(app, @alpha_change);
            app.Slider_alpha.Value = 0.5;
            app.Button_moveLeft = uibutton(app.Panel, 'push');
            app.Button_moveLeft.ButtonPushedFcn = createCallbackFcn(app, @moveLeft);
            app.Button_moveLeft.Text = '<';
            app.Button_moveRight = uibutton(app.Panel, 'push');
            app.Button_moveRight.ButtonPushedFcn = createCallbackFcn(app, @moveRight);
            app.Button_moveRight.Text = '>';
            app.Button_moveUp = uibutton(app.Panel, 'push');
            app.Button_moveUp.ButtonPushedFcn = createCallbackFcn(app, @moveUp);
            app.Button_moveUp.Text = '^';
            app.Button_moveDown = uibutton(app.Panel, 'push');
            app.Button_moveDown.ButtonPushedFcn = createCallbackFcn(app, @moveDown);
            app.Button_moveDown.Text = 'v';
            app.LabelDropDown = uilabel(app.Panel);
            app.LabelDropDown.Text = 'step';
            app.DropDown_step = uidropdown(app.Panel);
            app.DropDown_step.Items = {'1', '2', '5', '10', '20', '50', '100'};
            app.DropDown_step.ValueChangedFcn = createCallbackFcn(app, @step_change, true);
            app.DropDown_step.Value = '1';
            app.Button_write = uibutton(app.Panel, 'push');
            app.Button_write.ButtonPushedFcn = createCallbackFcn(app, @write);
            app.Button_write.Text = 'write';
            app.Button_split = uibutton(app.Panel, 'push');
            app.Button_split.ButtonPushedFcn = createCallbackFcn(app, @split);
            app.Button_split.Text = 'split';
            app.LabelEditField2 = uilabel(app.Panel);
            app.LabelEditField2.Text = 'pattern';
            app.EditField_pattern = uieditfield(app.Panel, 'text');
            app.LabelSpinner = uilabel(app.Panel);
            app.LabelSpinner.Text = 'max-zoom';
            app.Spinner_maxZoom = uispinner(app.Panel);
            app.Spinner_maxZoom.Limits = [1 8];
            app.Spinner_maxZoom.Value = 1;
            app.Button_extraCut = uibutton(app.Panel, 'push');
            app.Button_extraCut.ButtonPushedFcn = createCallbackFcn(app, @cut_extra);
            app.Button_extraCut.Text = 'cut';
            app.LabelListBox = uilabel(app.Panel);
            app.LabelListBox.Text = 'tip';
            app.ListBox_tip = uilistbox(app.Panel);
            app.ListBox_tip.Items = {'', '', ''};
            app.ListBox_tip.Value = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MapHandler()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end